classdef FruitLoop < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                     matlab.ui.Figure
        HarvestQAButton             matlab.ui.control.Button
        PerfectfruitEditField       matlab.ui.control.NumericEditField
        PerfectfruitEditFieldLabel  matlab.ui.control.Label
        FruitpickedEditField        matlab.ui.control.NumericEditField
        FruitpickedEditFieldLabel   matlab.ui.control.Label
        FruitsortedEditField        matlab.ui.control.NumericEditField
        FruitsortedEditFieldLabel   matlab.ui.control.Label
        Image                       matlab.ui.control.Image
        STOPButton                  matlab.ui.control.StateButton
        QAOnlyButton                matlab.ui.control.Button
        HarvestOnlyButton           matlab.ui.control.Button
        plot                        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        % place your class properties here

        %% Robot Properties
        modelLinearUR3 % Linear UR3 to be loaded
        modelPanda % Panda to be loaded

        %% Gui
        fruitPicked
        fruitSorted
        fruitPerfect

        %% Gripper Properties
        % LinearUR3 Gripper
        g1
        g2
        % Panda Gripper
        g3
        g4

        %% Initialise Class instances
        FruitSys % Fruit System Class
        objFunc % Object Function Class
        robotFunc % Robot Function Class

        %% Fruit Properties
        tree1_pos
        tree1_obj
        tree1_verts
        tree1_picked
        tree2_pos
        tree2_obj
        tree2_verts
        tree2_picked
        tree1_crate_pos
        tree2_crate_pos
        tree1_above_crate
        tree2_above_crate
        tree1_sorted_crate_pos
        tree2_sorted_crate_pos
        tree1_above_sorted_crate
        tree2_above_sorted_crate
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function init(app, varargin)
            %% Clean Terminal
            clc;
            
            %% Button status
            app.HarvestOnlyButton.Enable = false;
            app.QAOnlyButton.Enable = false;
            app.STOPButton.Enable = false;
            app.HarvestQAButton.Enable = false;

            %% Initialise first robot (LinearUR3) and plot
            % Create a blank 3D plot in the UIAxes component
            plot3(app.plot, [], [], []);
            % Set the axis limits
            axis(app.plot, [-2.2 1 -2 1.2 0.01 1.5]);
            hold on;

            %% Initialise instance of classes.
            app.objFunc = ObjectFunctions();
            app.FruitSys = FruitLoopSystem();
            app.robotFunc = RobotFunctions();
            %% Set up static environment.
            app.FruitSys.Environment();
            % Set Mandarin locations and initialise them.
            [app.tree1_pos, app.tree1_obj, app.tree1_verts, app.tree1_picked, app.tree2_pos, app.tree2_obj, app.tree2_verts, app.tree2_picked, app.tree1_crate_pos, app.tree2_crate_pos, app.tree1_above_crate, app.tree2_above_crate, app.tree1_sorted_crate_pos, app.tree2_sorted_crate_pos, app.tree1_above_sorted_crate, app.tree2_above_sorted_crate] = app.FruitSys.Mandarins();
            
            %% Initialise first robot (LinearUR3)
            % Robot
            app.modelLinearUR3 = LinearUR3(transl(0,0,0.02));
            % Gripper
            pos1 = (app.modelLinearUR3.model.fkineUTS(app.modelLinearUR3.model.getpos()))*transl(0,0.0127,0.0612)*trotx(pi/2); % Base position right gripper offset from UR3's end effector (0.0127 is the ditance of the grip from the base cebtre and 0.0612 is the depth of the base)
            pos2 = (app.modelLinearUR3.model.fkineUTS(app.modelLinearUR3.model.getpos()))*transl(0,-0.0127,0.0612)*trotx(pi/2); % Base position left gripper offset from UR3's end effector (-0.0127 is the ditance of the grip from the base cebtre and 0.0612 is the depth of the base)
            app.g1 = GripRight(pos1); % initiate right gripper
            app.g2 = GripLeft(pos2); % initial left gripper
            app.robotFunc.GripperMove(app.g1,app.g2,1); % Close Gripper to operating distance for Mandarin (open close 10 degrees)

            %% Initialise second robot (Panda)
            % Robot
            app.modelPanda = Panda(transl(-0.6,-0.8,0.02));
            % Gripper
            pos3 = (app.modelPanda.model.fkineUTS(app.modelPanda.model.getpos()))* transl(0,-0.0127,0.05)*trotx(-pi/2)*trotz(pi);  % Base position right gripper offset from Panda's end effector 
            pos4 = (app.modelPanda.model.fkineUTS(app.modelPanda.model.getpos()))* transl(0,0.0127,0.05)*trotx(-pi/2)*trotz(pi); % Base position left gripper offset from Panda's end effector 
            app.g3 = GripRight(pos3); % initiate right gripper
            app.g4 = GripLeft(pos4); % initial left gripper 
            app.robotFunc.GripperMove(app.g3,app.g4,1); % Close Gripper to operating distance for Mandarin (open close 10 degrees)

            %% Button settings.
            app.HarvestQAButton.Enable = true;
            app.HarvestOnlyButton.Enable = true;

        end

        % Button pushed function: HarvestOnlyButton
        function HarvestOnlyButtonPushed(app, event)
            %% Enable emergency stop
            app.STOPButton.Enable = true;
            app.HarvestQAButton.Enable = false;

            %% Harvest Tree 1
            display(['Tree 1 Harvest: Beginning picking process.']);
            app.fruitPicked = 0; % Mandarin harvest count
            
            for x = 1:size(app.tree1_pos, 1)
                % Look for Mandarin
                display(['Tree 1 Harvest: Look for Mandarin ', num2str(x)]);
                app.robotFunc.MoveRobot(app.modelLinearUR3,[app.tree1_pos(x,1),app.tree1_pos(x,2)-0.45,app.tree1_pos(x,3)],50,0,false,0,1,app.g1,app.g2,0);
                
                % Move to initial Mandarin location
                display(['Tree 1 Harvest: Go to Mandarin ', num2str(x)]);
                app.robotFunc.MoveRobot(app.modelLinearUR3,[app.tree1_pos(x,1),app.tree1_pos(x,2)-0.2,app.tree1_pos(x,3)],30,0,false,0,1,app.g1,app.g2,1);
            
                % Move Mandarins away from tree
                display(['Tree 1 Harvest: Pick Mandarin ', num2str(x), ' from tree.']);
                app.robotFunc.MoveRobot(app.modelLinearUR3,[app.tree1_pos(x,1),app.tree1_pos(x,2)-0.45,app.tree1_pos(x,3)],30,app.tree1_obj{x},true,app.tree1_verts{x},1,app.g1,app.g2,0);
            
                % Move Mandarins above crate
                display(['Tree 1 Harvest: Place Mandarin ', num2str(x), ' above crate.']);
                app.robotFunc.MoveRobot(app.modelLinearUR3,[app.tree1_crate_pos(x,1),app.tree1_crate_pos(x,2),app.tree1_crate_pos(x,3)+0.7],30,app.tree1_obj{x},true,app.tree1_verts{x},3,app.g1,app.g2,0);
             
                % Place Mandarins in crate
                display(['Tree 1 Harvest: Place Mandarin ', num2str(x), ' within the crate.']);
                app.robotFunc.MoveRobot(app.modelLinearUR3,[app.tree1_crate_pos(x,1),app.tree1_crate_pos(x,2),app.tree1_crate_pos(x,3)+0.2],30,app.tree1_obj{x},true,app.tree1_verts{x},2,app.g1,app.g2,0);
            
                % Lift End Effector from crate
                display(['Tree 1 Harvest: Lift gripper ', num2str(x), ' from the crate.']);
                app.robotFunc.MoveRobot(app.modelLinearUR3,[app.tree1_crate_pos(x,1),app.tree1_crate_pos(x,2),app.tree1_crate_pos(x,3)+0.6],30,0,false,0,2,app.g1,app.g2,2);
            
                % Face left
                app.robotFunc.MoveRobot(app.modelLinearUR3,[-0.6,0,0.5],30,0,false,0,0,app.g1,app.g2,2);

                % Count Mandarin picked.
                app.fruitPicked = app.fruitPicked + 1;
                display(['Tree 1 Harvest: The total number of Mandarins picked from tree 1 is ', num2str(x)]);
            end
            
            display(['Tree 1 Harvest: Completed.']);
            display(['=====================================']);
            
            
            %% Harvest Tree 2
            display(['Tree 2 Harvest: Beginning picking process.']);
            app.fruitPicked = 0; % Mandarin harvest count
            
            for j = 1:size(app.tree2_pos, 1)
            
                % Look for Mandarin
                display(['Tree 2 Harvest: Look for Mandarin ', num2str(x)]);
                app.robotFunc.MoveRobot(app.modelLinearUR3,[app.tree2_pos(x,1),app.tree2_pos(x,2)-0.45,app.tree2_pos(x,3)],50,0,false,0,1,app.g1,app.g2,0);
            
                % Move to initial Mandarin location
                display(['Tree 2 Harvest: Go to Mandarin ', num2str(j)]);
                app.robotFunc.MoveRobot(app.modelLinearUR3,[app.tree2_pos(j,1),app.tree2_pos(j,2)-0.2,app.tree2_pos(j,3)],30,0,false,0,1,app.g1,app.g2,1);
            
                % Move Mandarins away from tree
                display(['Tree 2 Harvest: Pick Mandarin ', num2str(j), ' from tree.']);
                app.robotFunc.MoveRobot(app.modelLinearUR3,[app.tree2_picked(j,1),app.tree2_picked(j,2)-0.45,app.tree2_picked(j,3)],30,app.tree2_obj{j},true,app.tree2_verts{j},1,app.g1,app.g2,0);
            
                % Move Mandarins to crate
                display(['Tree 2 Harvest: Place Mandarin ', num2str(j), ' above crate.']);
                app.robotFunc.MoveRobot(app.modelLinearUR3,[app.tree2_above_crate(j,1),app.tree2_above_crate(j,2),app.tree2_above_crate(j,3)+0.7],30,app.tree2_obj{j},true,app.tree2_verts{j},2,app.g1,app.g2,3);
            
                % Place Mandarins in crate
                display(['Tree 2 Harvest: Place Mandarin ', num2str(j), ' within the crate.']);
                app.robotFunc.MoveRobot(app.modelLinearUR3,[app.tree2_crate_pos(j,1),app.tree2_crate_pos(j,2),app.tree2_crate_pos(j,3)+0.2],30,app.tree2_obj{j},true,app.tree2_verts{j},2,app.g1,app.g2,0);
            
                % Lift End Effector from crate
                display(['Tree 2 Harvest: Lift the gripper ', num2str(j), ' from the crate.']);
                app.robotFunc.MoveRobot(app.modelLinearUR3,[app.tree2_above_crate(j,1),app.tree2_above_crate(j,2),app.tree2_above_crate(j,3)+0.7],30,0,false,0,2,app.g1,app.g2,2);
            
                % Count Mandarin picked.
                app.fruitPicked = app.fruitPicked + 1;
            end
            
            % Return Robot to beginning.
            app.robotFunc.MoveRobot(app.modelLinearUR3,[-0.2, 0, 0.65],50,0,false,0,3,app.g1,app.g2,1);
            
            display(['Tree 2 Harvest: Completed.']);
            display(['=====================================']);

            % Once harvest is complete, we can start sorting.
            app.QAOnlyButton.Enable = true;
            app.HarvestOnlyButton.Enable = false;

        end

        % Button pushed function: QAOnlyButton
        function QAOnlyButtonPushed(app, event)
             %% Conduct Quality Control on set 1
            display(['Quality Control S1: Begin Filtering.']);
            app.fruitSorted = 0;
            for x = 1:size(app.tree1_sorted_crate_pos, 1)
                % Look for Mandarin in unsorted crate
                display(['Quality Control S1: Look for Mandarins to filter ', num2str(x)]);
                app.robotFunc.MoveRobot(app.modelPanda,[app.tree1_above_crate(x,1),app.tree1_above_crate(x,2),app.tree1_above_crate(x,3)],50,0,false,0,2,app.g3,app.g4,0);
                
                % Go to mandarin in unsorted crate and grip
                display(['Quality Control S1: Go to Mandarin ', num2str(x)]);
                app.robotFunc.MoveRobot(app.modelPanda,[app.tree1_crate_pos(x,1),app.tree1_crate_pos(x,2),app.tree1_crate_pos(x,3)+0.2],50,0,false,0,2,app.g3,app.g4,1);
                
                % Pick up mandarin from unsorted crate
                display(['Quality Control S1: Pick Mandarin ', num2str(x), ' from tree.']);
                app.robotFunc.MoveRobot(app.modelPanda,[app.tree1_above_crate(x,1),app.tree1_above_crate(x,2),app.tree1_above_crate(x,3)+0.2],50,app.tree1_obj{x},true,app.tree1_verts{x},2,app.g3,app.g4,0);
            
                % Move mandarin above sorted crate
                display(['Quality Control S1: Place Mandarin ', num2str(x), ' above crate.']);
                app.robotFunc.MoveRobot(app.modelPanda,[app.tree1_above_sorted_crate(x,1),app.tree1_above_sorted_crate(x,2),app.tree1_above_sorted_crate(x,3)+0.5],50,app.tree1_obj{x},true,app.tree1_verts{x},2,app.g3,app.g4,0);
             
                % Place Mandarins in sorted crate
                display(['Quality Control S1: Place Mandarin ', num2str(x), ' within the crate.']);
                app.robotFunc.MoveRobot(app.modelPanda,[app.tree1_sorted_crate_pos(x,1),app.tree1_sorted_crate_pos(x,2),app.tree1_sorted_crate_pos(x,3)+0.2],50,app.tree1_obj{x},true,app.tree1_verts{x},2,app.g3,app.g4,2);
            
                % Release mandarin and lift away from sorted crate
                display(['Quality Control S1: Lift gripper ', num2str(x), ' from the crate.']);
                app.robotFunc.MoveRobot(app.modelPanda,[app.tree1_above_sorted_crate(x,1),app.tree1_above_sorted_crate(x,2),app.tree1_above_sorted_crate(x,3)+0.5],50,0,false,0,2,app.g3,app.g4,0);
            
                % Count Mandarin picked.
                display(['Quality Control S1: The total number of Mandarins picked from tree 1 is ', num2str(x)]);
                app.fruitSorted = app.fruitSorted + 1;
            end
            
            display(['Quality Control S1: Completed Filtering.']);
            display(['=====================================']);
            
            %% Conduct Quality Control on set 2
            display(['Quality Control S2: Begin Filtering.']);
        
            for x = 1:size(app.tree1_sorted_crate_pos, 1)
                % Look for Mandarin in unsorted crate
                display(['Quality Control S1: Look for Mandarins to filter ', num2str(x)]);
                app.robotFunc.MoveRobot(app.modelPanda,[app.tree2_above_crate(x,1),app.tree2_above_crate(x,2),app.tree2_above_crate(x,3)],25,0,false,0,2,app.g3,app.g4,0);
                
                % Go to mandarin in unsorted crate and grip
                display(['Quality Control S1: Go to Mandarin ', num2str(x)]);
                app.robotFunc.MoveRobot(app.modelPanda,[app.tree2_crate_pos(x,1),app.tree2_crate_pos(x,2),app.tree2_crate_pos(x,3)+0.2],30,0,false,0,2,app.g3,app.g4,1);
                
                % Pick up mandarin from unsorted crate
                display(['Quality Control S1: Pick Mandarin ', num2str(x), ' from tree.']);
                app.robotFunc.MoveRobot(app.modelPanda,[app.tree2_above_crate(x,1),app.tree2_above_crate(x,2),app.tree2_above_crate(x,3)+0.2],40,app.tree2_obj{x},true,app.tree2_verts{x},2,app.g3,app.g4,0);
            
                % Move mandarin above sorted crate
                display(['Quality Control S1: Place Mandarin ', num2str(x), ' above crate.']);
                app.robotFunc.MoveRobot(app.modelPanda,[app.tree2_above_sorted_crate(x,1),app.tree2_above_sorted_crate(x,2),app.tree2_above_sorted_crate(x,3)+0.5],40,app.tree2_obj{x},true,app.tree2_verts{x},2,app.g3,app.g4,0);
             
                % Place Mandarins in sorted crate
                display(['Quality Control S1: Place Mandarin ', num2str(x), ' within the crate.']);
                app.robotFunc.MoveRobot(app.modelPanda,[app.tree2_sorted_crate_pos(x,1),app.tree2_sorted_crate_pos(x,2),app.tree2_sorted_crate_pos(x,3)+0.2],40,app.tree2_obj{x},true,app.tree2_verts{x},2,app.g3,app.g4,2);
            
                % Release mandarin and lift away from sorted crate
                display(['Quality Control S1: Lift gripper ', num2str(x), ' from the crate.']);
                app.robotFunc.MoveRobot(app.modelPanda,[app.tree2_above_sorted_crate(x,1),app.tree2_above_sorted_crate(x,2),app.tree2_above_sorted_crate(x,3)+0.5],40,0,false,0,2,app.g3,app.g4,0);
            
                % Count Mandarin picked.
                display(['Quality Control S1: The total number of Mandarins picked from tree 1 is ', num2str(x)]);
                app.fruitSorted = app.fruitSorted + 1;
            end
            % Return Robot to beginning.
            app.robotFunc.MoveRobot(app.modelPanda,[-1.2,-0.7,1.3],40,0,false,0,0,app.g3,app.g4,2);
            display(['Quality Control S2: Completed Filtering.']);
            display(['=====================================']);
        end

        % Value changed function: FruitpickedEditField
        function FruitpickedEditFieldValueChanged(app, event)
            app.FruitpickedEditField.Value = app.fruitPicked;
        end

        % Value changed function: FruitsortedEditField
        function FruitsortedEditFieldValueChanged(app, event)
           app.FruitpickedEditField.Value = app.fruitSorted;
        end

        % Button pushed function: HarvestQAButton
        function HarvestQAButtonPushed(app, event)
            %% Enable emergency stop
            app.STOPButton.Enable = true;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'TestRobotPlot_V2';
            app.figure1.Tag = 'figure1';
            app.plot = uiaxes(app.figure1);
            xlabel(app.plot, 'X')
            ylabel(app.plot, 'Y')
            app.plot.Tag = 'axes1';
            app.HarvestOnlyButton = uibutton(app.figure1, 'push');
            app.HarvestOnlyButton.ButtonPushedFcn = createCallbackFcn(app, @HarvestOnlyButtonPushed, true);
            app.HarvestOnlyButton.Text = 'Harvest Only';
            app.QAOnlyButton = uibutton(app.figure1, 'push');
            app.QAOnlyButton.ButtonPushedFcn = createCallbackFcn(app, @QAOnlyButtonPushed, true);
            app.QAOnlyButton.Text = 'QA Only';
            app.STOPButton = uibutton(app.figure1, 'state');
            app.STOPButton.Text = 'STOP';
            app.Image = uiimage(app.figure1);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'FruitLoopLogo.png');
            app.FruitsortedEditFieldLabel = uilabel(app.figure1);
            app.FruitsortedEditFieldLabel.Text = 'Fruit sorted';
            app.FruitsortedEditField = uieditfield(app.figure1, 'numeric');
            app.FruitsortedEditField.ValueChangedFcn = createCallbackFcn(app, @FruitsortedEditFieldValueChanged, true);
            app.FruitpickedEditFieldLabel = uilabel(app.figure1);
            app.FruitpickedEditFieldLabel.Text = 'Fruit picked';
            app.FruitpickedEditField = uieditfield(app.figure1, 'numeric');
            app.FruitpickedEditField.ValueChangedFcn = createCallbackFcn(app, @FruitpickedEditFieldValueChanged, true);
            app.PerfectfruitEditFieldLabel = uilabel(app.figure1);
            app.PerfectfruitEditFieldLabel.Text = 'Perfect fruit';
            app.PerfectfruitEditField = uieditfield(app.figure1, 'numeric');
            app.HarvestQAButton = uibutton(app.figure1, 'push');
            app.HarvestQAButton.ButtonPushedFcn = createCallbackFcn(app, @HarvestQAButtonPushed, true);
            app.HarvestQAButton.Text = 'Harvest & QA';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FruitLoop(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)init(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end